classdef guiEDM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        NumberofPointsintheSetEditFieldLabel  matlab.ui.control.Label
        numberOfPoints                 matlab.ui.control.NumericEditField
        DimensionLabel                 matlab.ui.control.Label
        dimension                      matlab.ui.control.NumericEditField
        AreaenteronesideofthesquareLabel  matlab.ui.control.Label
        scaling                        matlab.ui.control.NumericEditField
        NumberofConfigurationEditFieldLabel  matlab.ui.control.Label
        numberOfConfiguration          matlab.ui.control.NumericEditField
        btnPlot                        matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        NumberofMissingDistancesPanel  matlab.ui.container.Panel
        MaxEditFieldLabel              matlab.ui.control.Label
        missingMax                     matlab.ui.control.NumericEditField
        StepEditFieldLabel             matlab.ui.control.Label
        missingStep                    matlab.ui.control.NumericEditField
        MinEditField_3Label            matlab.ui.control.Label
        missingMin                     matlab.ui.control.NumericEditField
        DistanceMeasurementErrorPanel  matlab.ui.container.Panel
        MinEditField_2Label            matlab.ui.control.Label
        measureMin                     matlab.ui.control.NumericEditField
        MaxEditField_2Label            matlab.ui.control.Label
        measureMax                     matlab.ui.control.NumericEditField
        StepEditField_2Label           matlab.ui.control.Label
        measureStep                    matlab.ui.control.NumericEditField
        ErrorinDropDownLabel           matlab.ui.control.Label
        ErrorinDropDown                matlab.ui.control.DropDown
        AlgorithmDropDownLabel         matlab.ui.control.Label
        AlgorithmDropDown              matlab.ui.control.DropDown
    end


    methods (Access = private)
    
        function [n,d,scale,n_del,m_error,n_config,algorithm,errorType] = GetInputs(app)
            n = app.numberOfPoints.Value;
            d = app.dimension.Value;
            scale = app.scaling.Value;
            
            n_del_min = app.missingMin.Value;
            n_del_max = app.missingMax.Value;
            n_del_step = app.missingStep.Value;
            n_del = n_del_min:n_del_step:n_del_max;
            
            m_error_min = app.measureMin.Value;
            m_error_max = app.measureMax.Value;
            m_error_step = app.measureStep.Value;
            m_error = m_error_min:m_error_step:m_error_max;
          
            n_config = app.numberOfConfiguration.Value;
            algorithm = app.AlgorithmDropDown.Value;
            errorType = app.ErrorinDropDown.Value;
        end


        function [DELTA] = GetRecoveryError(~,n,d,scale,n_del,m_error,n_config,algorithm,errorType)
    
            DELTA = zeros(numel(n_del), numel(m_error));
            
            I = logical(eye(n));
        
            if strcmp(errorType,'Meters') 
                    m_error = m_error/(scale*sqrt(2)); % scaling distance value between (0,1). 
                                                       % See https://math.stackexchange.com/questions/3288302/re-scaling-the-outputs-of-a-matrix-completion-algorithm
                                                       % for explanation
            end
        
            for i_err = 1:numel(m_error)
                for i_del = 1:numel(n_del)
                    err_in = zeros(4, 1);
                    for i_config = 1:n_config
        
                        fprintf('measurement error: %14.6e, # of deletions: %d, config #: %d \n', m_error(i_err), n_del(i_del), i_config);
                        X = 0 + (scale-0)*rand(d, n); % point set in coordinates [0,scale]×[0,scale]
                        % create new coordinate system with corresponding set [0,1/sqrt(2)]×[0,1/sqrt(2)]
                        X = X/(scale*sqrt(2));
                        D = edm(X, X);       % create EDM
        
                        % apply distance measurement error
                        if strcmp(errorType,'Meters') 
                            temp = sqrt(D) + m_error(i_err)*rand(n); % random error ranging from 0 to m_error(i_err)
                        else 
                            temp = sqrt(D) + sqrt(D)*m_error(i_err)*0.01;
                        end
                        DN = temp.^2;
                        DN(I) = 0; % DN is an EDM with distance measurement error
        
                        % randomly delete entries from the EDM
                        W = random_deletion_mask(n, n_del(i_del));
                        DNM = DN;
                        DNM(~W) = 0; % DNM is an EDM with distance measurement error and with randomly deleted entries
        
        
                        if strcmp(algorithm,'Rank Alteration') 
                            % apply RankCompleteEDM
                            E = rank_complete_edm(DNM, W, d, 0);
                        elseif strcmp(algorithm,'Alternating Descent')    
                            % apply Alternating Descent
                            [~, E] = alternating_descent(DN .* W, d);
                        else
                            msg = 'Algorithm not defined';
                            error(msg);
                            
                        end
        
                        dif = abs(sqrt(E) - sqrt(D));
                        dif(W) = 0; 
                        %errMax = errMax + max(dif(:)); % measure maximum error among missing entries
                        if nnz(dif) > 0
                            err_in(2) = err_in(2) + nanmean(nonzeros(dif));
                        end
                    end 
                    DELTA(i_del, i_err) = err_in(2);
                end
            end
            DELTA = (DELTA / n_config);
            
            % convert the distances back to original coordinate system
            DELTA = (DELTA*scale*sqrt(2));
        end


    end


    methods (Access = private)

        % Button pushed function: btnPlot
        function btnPlotPushed(app, event)
            [n,d,scale,n_del,m_error,n_config,algorithm,errorType] = GetInputs(app);
            [DELTA] = GetRecoveryError(app,n,d,scale,n_del,m_error,n_config,algorithm,errorType);
            
            % Plot #of missing entries in EDM  vs distance recovery error for different
            % distance measurement errors
            plot(app.UIAxes,n_del,DELTA,'LineWidth', 1);
            ylabel(app.UIAxes,'Recovery Error (R)', 'fontsize', 16);
            xlabel(app.UIAxes,'# of  Missing Distances (M)', 'fontsize', 16);
            %set(app.UIAxes,gca,'xtick',min(n_del):max(n_del));
            leg = string.empty(0,numel(m_error));
            for i_err = 1:numel(m_error)
                leg(i_err) = strcat('measurement error = ', num2str(m_error(i_err)));
            end
            legend(app.UIAxes,leg, 'Location','NorthWest', 'fontsize', 14);
            grid(app.UIAxes,'on');
            title(app.UIAxes,['Number of nodes = ',num2str(n)], 'fontsize', 16);


        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.NumberofPointsintheSetEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofPointsintheSetEditFieldLabel.Text = 'Number of Points in the Set';
            app.numberOfPoints = uieditfield(app.UIFigure, 'numeric');
            app.numberOfPoints.Value = 10;
            app.DimensionLabel = uilabel(app.UIFigure);
            app.DimensionLabel.Text = 'Dimension';
            app.dimension = uieditfield(app.UIFigure, 'numeric');
            app.dimension.Value = 2;
            app.AreaenteronesideofthesquareLabel = uilabel(app.UIFigure);
            app.AreaenteronesideofthesquareLabel.Text = 'Area (enter one side of the square)';
            app.scaling = uieditfield(app.UIFigure, 'numeric');
            app.scaling.Value = 50;
            app.NumberofConfigurationEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofConfigurationEditFieldLabel.Text = 'Number of Configuration ';
            app.numberOfConfiguration = uieditfield(app.UIFigure, 'numeric');
            app.numberOfConfiguration.Value = 100;
            app.btnPlot = uibutton(app.UIFigure, 'push');
            app.btnPlot.ButtonPushedFcn = createCallbackFcn(app, @btnPlotPushed, true);
            app.btnPlot.Text = 'Plot';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.NumberofMissingDistancesPanel = uipanel(app.UIFigure);
            app.NumberofMissingDistancesPanel.Title = 'Number of Missing Distances';
            app.MaxEditFieldLabel = uilabel(app.NumberofMissingDistancesPanel);
            app.MaxEditFieldLabel.Text = 'Max';
            app.missingMax = uieditfield(app.NumberofMissingDistancesPanel, 'numeric');
            app.missingMax.Value = 20;
            app.StepEditFieldLabel = uilabel(app.NumberofMissingDistancesPanel);
            app.StepEditFieldLabel.Text = 'Step';
            app.missingStep = uieditfield(app.NumberofMissingDistancesPanel, 'numeric');
            app.missingStep.Value = 5;
            app.MinEditField_3Label = uilabel(app.NumberofMissingDistancesPanel);
            app.MinEditField_3Label.Text = 'Min';
            app.missingMin = uieditfield(app.NumberofMissingDistancesPanel, 'numeric');
            app.DistanceMeasurementErrorPanel = uipanel(app.UIFigure);
            app.DistanceMeasurementErrorPanel.Title = 'Distance Measurement Error';
            app.MinEditField_2Label = uilabel(app.DistanceMeasurementErrorPanel);
            app.MinEditField_2Label.Text = 'Min';
            app.measureMin = uieditfield(app.DistanceMeasurementErrorPanel, 'numeric');
            app.measureMin.Value = 1;
            app.MaxEditField_2Label = uilabel(app.DistanceMeasurementErrorPanel);
            app.MaxEditField_2Label.Text = 'Max';
            app.measureMax = uieditfield(app.DistanceMeasurementErrorPanel, 'numeric');
            app.measureMax.Value = 3;
            app.StepEditField_2Label = uilabel(app.DistanceMeasurementErrorPanel);
            app.StepEditField_2Label.Text = 'Step';
            app.measureStep = uieditfield(app.DistanceMeasurementErrorPanel, 'numeric');
            app.measureStep.Value = 1;
            app.ErrorinDropDownLabel = uilabel(app.DistanceMeasurementErrorPanel);
            app.ErrorinDropDownLabel.Text = 'Error in';
            app.ErrorinDropDown = uidropdown(app.DistanceMeasurementErrorPanel);
            app.ErrorinDropDown.Items = {'Percentage', 'Meters'};
            app.ErrorinDropDown.Value = 'Meters';
            app.AlgorithmDropDownLabel = uilabel(app.UIFigure);
            app.AlgorithmDropDownLabel.Text = 'Algorithm';
            app.AlgorithmDropDown = uidropdown(app.UIFigure);
            app.AlgorithmDropDown.Items = {'Rank Alteration', 'Alternating Descent'};
            app.AlgorithmDropDown.Value = 'Alternating Descent';
        end
    end

    methods (Access = public)

        % Construct app
        function app = guiEDM

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end